classdef simulation < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        RunCheckBox                matlab.ui.control.CheckBox
        number_order_3             matlab.ui.control.Label
        pi_estimate                matlab.ui.control.Label
        Planks                     matlab.ui.control.Slider
        ParallelPlanksSliderLabel  matlab.ui.control.Label
        Throws_input               matlab.ui.control.NumericEditField
        ThrowsEditFieldLabel       matlab.ui.control.Label
        Length_input               matlab.ui.control.NumericEditField
        LengthEditFieldLabel       matlab.ui.control.Label
        set_values_label           matlab.ui.control.Label
        number_order_2             matlab.ui.control.Label
        line_title_1               matlab.ui.control.Label
        pick_simulation            matlab.ui.container.ButtonGroup
        Square_Root_two_Button     matlab.ui.control.RadioButton
        Hexagons_Button            matlab.ui.control.RadioButton
        Squares_Button             matlab.ui.control.RadioButton
        Needles_Button             matlab.ui.control.RadioButton
        side_line_1                matlab.ui.control.Label
        side_line_2                matlab.ui.control.Label
        line_title_2               matlab.ui.control.Label
        side_line_3                matlab.ui.control.Label
        number_order_1             matlab.ui.control.Label
        black_line_title           matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        MonteCarloIrrationalNumbersSimulationLabel  matlab.ui.control.Label
        Width_input                matlab.ui.control.NumericEditField
        WidthLabel                 matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)


        function RunNeedlesPi(comp)
            width = comp.Width_input.Value;
            length = comp.Length_input.Value;
            throws = comp.Throws_input.Value;
            Parallel_Planks = round(comp.Planks.Value);
            limit = 0;
            
            for i = 1:throws

                if limit < 100

                    % Randomly generate the position of the needle's center (x, y)
                    needle_centre_x = rand();
                    needle_centre_y = rand() * width;
        
                    % Randomly generate the angle for the needle
                    angle = rand() * (2 * pi);

                    % Calculate the scaling factor based on the ratio of length to width
                    scaling_factor = length / width;
                    
                    needle_x1 = needle_centre_x + (1/2 * length) * cos(angle) / scaling_factor;
                    needle_y1 = needle_centre_y + (1/2 * length) * sin(angle) / scaling_factor;
                    needle_x2 = needle_centre_x - (1/2 * length) * cos(angle) / scaling_factor;
                    needle_y2 = needle_centre_y - (1/2 * length) * sin(angle) / scaling_factor;
        
                    % Plot the needle
                    line(comp.UIAxes, [needle_x1, needle_x2], [needle_y1, needle_y2], 'Color', 'b');
                    
                    limit = limit + 1;
                
                end
                    
            end

            
            ylim(comp.UIAxes, [0, width * Parallel_Planks]);
            ylim(comp.UIAxes, [0, width]);

        end


        function RunSquaresPi(comp)
            width = comp.Width_input.Value;
            length = comp.Length_input.Value;
            throws = comp.Throws_input.Value;
            %Parallel_Planks = round(comp.Planks.Value);
            limit = 0;

            for i = 1:throws

                if limit < 100

                    square_side_x = rand();
                    square_side_y = rand() * width;
                    angle = rand() * (2 * pi);  
            
                    square_x = zeros(1, 4);
                    square_y = zeros(1, 4);
            
                    previous_point_x = square_side_x;
                    previous_point_y = square_side_y;
            
                    for side = 1:4
                        side_x = previous_point_x + (length * (cos(angle + (side-1) * pi/2)));
                        side_y = previous_point_y + (length * (sin(angle + (side-1) * pi/2)));
            
                        previous_point_x = side_x;
                        previous_point_y = side_y;
                        square_x(side) = side_x;
                        square_y(side) = side_y;
                    end
            
                    hold(comp.UIAxes, 'on');
                    plot(comp.UIAxes, [square_x, square_x(1)], [square_y, square_y(1)], 'b-');
                    hold(comp.UIAxes, 'off');
    
                    xlabel(comp.UIAxes, 'X-axis');
                    ylabel(comp.UIAxes, 'Y-axis');
                    title(comp.UIAxes, 'Buffon Needle Simulation');
                    axis(comp.UIAxes, 'equal');
                    grid(comp.UIAxes, 'on');
                    
                    limit = limit + 1;
            
                end
            end
        end


        function RunHexagonRootTwo(comp)

            width = comp.Width_input.Value;
            length = comp.Length_input.Value;
            throws = comp.Throws_input.Value;
            limit = 0;

            for i = 1:throws
                hexagon_side_x = rand();
                hexagon_side_y = rand() * width;
                angle = rand() * (2 * pi);
                previous_point_x = hexagon_side_x;
                previous_point_y = hexagon_side_y;
        
                for side = 1:6
              
                    side_x = previous_point_x + (length * (cos(angle + (side-1) * pi/3)));
                    side_y = previous_point_y + (length * (sin(angle + (side-1) * pi/3)));
                    previous_point_x = side_x;
                    previous_point_y = side_y;
        
                    hexagon_x(i, side) = side_x;
                    hexagon_y(i, side) = side_y;
                end

                limit = limit + 1;
            end

            hold(comp.UIAxes, 'on');
            for iteration = 1:throws
                plot(comp.UIAxes, [hexagon_x(i, :), hexagon_x(i, 1)], ...
                                  [hexagon_y(i, :), hexagon_y(i, 1)], 'b-');
            end
            hold(comp.UIAxes, 'off');
        
            xlabel(comp.UIAxes, 'X-axis');
            ylabel(comp.UIAxes, 'Y-axis');
            title(comp.UIAxes, 'Hexagon Root Two Simulation');
            axis(comp.UIAxes, 'equal');
            grid(comp.UIAxes, 'on');

        end 


       function RunSquareRootTwo(comp)
            width = comp.Width_input.Value;
            length = comp.Length_input.Value;
            throws = comp.Throws_input.Value;
            limit = 0;
        
            square_x = zeros(throws, 4);
            square_y = zeros(throws, 4);
        
            for iteration = 1:throws
                if limit < 100  
                    square_side_x = rand();
                    square_side_y = rand() * width;
                    angle = rand() * (2 * pi);
        
                    previous_point_x = square_side_x;
                    previous_point_y = square_side_y;
        
                    for side = 1:4
                        side_x = previous_point_x + (length * cos(angle + (side-1) * pi/2));
                        side_y = previous_point_y + (length * sin(angle + (side-1) * pi/2));
                        previous_point_x = side_x;
                        previous_point_y = side_y;
        
                        square_x(iteration, side) = side_x;
                        square_y(iteration, side) = side_y;
                    end

                    limit = limit + 1;
                end
            end
            
            hold(comp.UIAxes, 'on');
            for iteration = 1:throws
                plot(comp.UIAxes, [square_x(iteration, :), square_x(iteration, 1)], ...
                                  [square_y(iteration, :), square_y(iteration, 1)], 'b-');
            end
            hold(comp.UIAxes, 'off');
        
            xlabel(comp.UIAxes, 'X-axis');
            ylabel(comp.UIAxes, 'Y-axis');
            title(comp.UIAxes, 'Square Root Two Simulation');
            axis(comp.UIAxes, 'equal');
            grid(comp.UIAxes, 'on');
        end


    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RunCheckBox
        function RunCheckBoxValueChanged(comp, event)

            value = comp.RunCheckBox.Value;
            width = comp.Width_input.Value;
            length = comp.Length_input.Value;
            throws = comp.Throws_input.Value;
            Parallel_Planks = round(comp.Planks.Value);
            
            if value == 1


                cla(comp.UIAxes);
                grid(comp.UIAxes, 'on');
                set(comp.UIAxes, 'YGrid', 'on', 'YMinorGrid', 'off', 'XGrid', 'off', 'XMinorGrid', 'off');
                set(comp.UIAxes, 'YTick', linspace(0, width * Parallel_Planks, Parallel_Planks + 1));
            
                selectedRadioButton = comp.pick_simulation.SelectedObject;
    
                if selectedRadioButton == comp.Needles_Button
                    comp.RunNeedlesPi();
                    p = BuffonStandardPi(width, length, throws);
                    comp.pi_estimate.Text = sprintf('Estimation of π: %f', p);
                
                elseif selectedRadioButton == comp.Squares_Button
                    comp.RunSquaresPi();
                    p = BuffonSquaresPi(width, length, throws);
                    comp.pi_estimate.Text = sprintf('Estimation of π: %f', p);
                
                elseif selectedRadioButton == comp.Hexagons_Button
                    comp.RunHexagonRootTwo();
                    p = BuffonHexagonRootTwo(width, length, throws);
                    comp.pi_estimate.Text = sprintf('Estimation of √2: %f', p);
                
                elseif selectedRadioButton == comp.Square_Root_two_Button
                    comp.RunSquareRootTwo();
                    p = BuffonSquaresRootTwo(width, length, throws);
                    comp.pi_estimate.Text = sprintf('Estimation of √2: %f', p);
                end
   
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 520 636];
            comp.BackgroundColor = [0.9843 0.8431 0.5529];
            comp.UIAxes = uiaxes(comp);
            title(comp.UIAxes, 'Simulation')
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'Y')
            zlabel(comp.UIAxes, 'Z')
            comp.WidthLabel = uilabel(comp);
            comp.WidthLabel.Text = 'Width of planks ';
            comp.Width_input = uieditfield(comp, 'numeric');
            comp.MonteCarloIrrationalNumbersSimulationLabel = uilabel(comp);
            comp.MonteCarloIrrationalNumbersSimulationLabel.Text = 'Monte Carlo Irrational Numbers Simulation';
            comp.Image = uiimage(comp);
            comp.Image.ImageSource = 'pi-image.jpg';
            comp.black_line_title = uilabel(comp);
            comp.number_order_1 = uilabel(comp);
            comp.number_order_1.Text = '1)';
            comp.side_line_3 = uilabel(comp);
            comp.line_title_2 = uilabel(comp);
            comp.side_line_2 = uilabel(comp);
            comp.side_line_1 = uilabel(comp);
            comp.pick_simulation = uibuttongroup(comp);
            comp.pick_simulation.Title = 'Pick the simulation you want to run :';
            comp.Needles_Button = uiradiobutton(comp.pick_simulation);
            comp.Needles_Button.Text = 'Needles π 🪡 ';
            comp.Needles_Button.Value = true;
            comp.Squares_Button = uiradiobutton(comp.pick_simulation);
            comp.Squares_Button.Text = 'Squares π 🎲 ';
            comp.Hexagons_Button = uiradiobutton(comp.pick_simulation);
            comp.Hexagons_Button.Text = 'Hexagons √2🕸️';
            comp.Square_Root_two_Button = uiradiobutton(comp.pick_simulation);
            comp.Square_Root_two_Button.Text = 'Squares √2 ✌️ ';
            comp.line_title_1 = uilabel(comp);
            comp.number_order_2 = uilabel(comp);
            comp.number_order_2.Text = '2)';
            comp.set_values_label = uilabel(comp);
            comp.set_values_label.Text = 'Select the values for the simulation:';
            comp.LengthEditFieldLabel = uilabel(comp);
            comp.LengthEditFieldLabel.Text = 'Length';
            comp.Length_input = uieditfield(comp, 'numeric');
            comp.ThrowsEditFieldLabel = uilabel(comp);
            comp.ThrowsEditFieldLabel.Text = 'Throws';
            comp.Throws_input = uieditfield(comp, 'numeric');
            comp.ParallelPlanksSliderLabel = uilabel(comp);
            comp.ParallelPlanksSliderLabel.Text = 'Parallel Planks';
            comp.Planks = uislider(comp);
            comp.Planks.Limits = [0 10];
            comp.Planks.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            comp.pi_estimate = uilabel(comp);
            comp.pi_estimate.Text = '';
            comp.number_order_3 = uilabel(comp);
            comp.number_order_3.Text = '3)';
            comp.RunCheckBox = uicheckbox(comp);
            comp.RunCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @RunCheckBoxValueChanged, true);
            comp.RunCheckBox.Text = 'Run';
        end
    end
end