classdef simulation_final < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        RUNLabel                   matlab.ui.control.Label
        CheckBox                   matlab.ui.control.CheckBox
        number_order_3             matlab.ui.control.Label
        pi_estimate                matlab.ui.control.Label
        Planks                     matlab.ui.control.Slider
        ParallelPlanksSliderLabel  matlab.ui.control.Label
        Throws_input               matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        Length_input               matlab.ui.control.NumericEditField
        LengthEditFieldLabel       matlab.ui.control.Label
        set_values_label           matlab.ui.control.Label
        number_order_2             matlab.ui.control.Label
        line_title_1               matlab.ui.control.Label
        pick_simulation            matlab.ui.container.ButtonGroup
        Square_Root_two_Button     matlab.ui.control.RadioButton
        Hexagons_Button            matlab.ui.control.RadioButton
        Squares_Button             matlab.ui.control.RadioButton
        Needles_Button             matlab.ui.control.RadioButton
        side_line_1                matlab.ui.control.Label
        side_line_2                matlab.ui.control.Label
        line_title_2               matlab.ui.control.Label
        side_line_3                matlab.ui.control.Label
        number_order_1             matlab.ui.control.Label
        black_line_title           matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        MonteCarloIrrationalNumbersLabel  matlab.ui.control.Label
        Width_input                matlab.ui.control.NumericEditField
        WidthLabel                 matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)

        function Click_Needle(comp, src, event)

            set(src, 'Color', 'red');

        end
        
        function RunNeedlesPi(comp)
            width = comp.Width_input.Value;
            length = comp.Length_input.Value;
            throws = comp.Throws_input.Value;
            Parallel_Planks = round(comp.Planks.Value);
            limit = 0;
            
            for i = 1:throws

                if limit < 100

                    needle_centre_x = rand();
                    needle_centre_y = rand() * width;
                    angle = rand() * (2 * pi);

                    x_scaler = randi([0, (Parallel_Planks-1) * width]);
                    y_scaler = randi([0, (Parallel_Planks-1) * width]);
                    y_scaler = width * floor(y_scaler / width);

                    
                    needle_x1 = needle_centre_x + (1/2 * length) * cos(angle) + x_scaler;
                    needle_y1 = needle_centre_y + (1/2 * length) * sin(angle) + y_scaler;
                    needle_x2 = needle_centre_x - (1/2 * length) * cos(angle) + x_scaler;
                    needle_y2 = needle_centre_y - (1/2 * length) * sin(angle) + y_scaler;
        
                    % Plot the needle
                    line(comp.UIAxes, [needle_x1, needle_x2], [needle_y1, needle_y2], 'Color', '#376d73', ...
                        'ButtonDownFcn', @(src, event) Click_Needle(comp, src, event));
                    
                    xlabel(comp.UIAxes, 'X-axis');
                    ylabel(comp.UIAxes, 'Y-axis');
                    title(comp.UIAxes, 'Needles π Simulation');
                    
                    limit = limit + 1;
                
                end
                    
            end

            
            ylim(comp.UIAxes, [0, width * Parallel_Planks]);
            xlim(comp.UIAxes, [0, width * Parallel_Planks]);
            
        end


        function RunSquaresPi(comp)
            width = comp.Width_input.Value;
            length = comp.Length_input.Value;
            throws = comp.Throws_input.Value;
            Parallel_Planks = round(comp.Planks.Value);
            limit = 0;

            ylim(comp.UIAxes, [0, width * Parallel_Planks]);
            xlim(comp.UIAxes, [0, width * Parallel_Planks]);

            ticks = 0:width:width * Parallel_Planks;
            xticks(comp.UIAxes, ticks);


            for i = 1:throws

                if limit < 100

                    square_side_x = rand();
                    square_side_y = rand() * width;
                    angle = rand() * (2 * pi);  
            
                    square_x = zeros(1, 4);
                    square_y = zeros(1, 4);
            
                    previous_point_x = square_side_x;
                    previous_point_y = square_side_y;

                    x_scaler = randi([0, (Parallel_Planks-1) * width]);
                    y_scaler = randi([0, (Parallel_Planks-1) * width]);
                    y_scaler = width * floor(y_scaler / width);
            
                    for side = 1:4
                        side_x = previous_point_x + (length * (cos(angle + (side-1) * pi/2 )));
                        side_y = previous_point_y + (length * (sin(angle + (side-1) * pi/2 )));
            
                        previous_point_x = side_x;
                        previous_point_y = side_y;

                        square_x(side) = side_x;
                        square_y(side) = side_y;
                    end

                    square_x = square_x + x_scaler;
                    square_y = square_y + y_scaler;

                    hold(comp.UIAxes, 'on');
                    plot(comp.UIAxes, [square_x, square_x(1)], [square_y, square_y(1)], '-', 'Color', '#376d73');
                    hold(comp.UIAxes, 'off');
                    
                    xlabel(comp.UIAxes, 'X-axis');
                    ylabel(comp.UIAxes, 'Y-axis');
                    title(comp.UIAxes, 'Squares π Simulation');
                    
        
                    grid(comp.UIAxes, 'on');
                    set(comp.UIAxes, 'XGrid', 'off');
                    
                    limit = limit + 1;
            
                end
                
            end
        end


         function RunSquareRootTwo(comp)
            width = comp.Width_input.Value;
            length = comp.Length_input.Value;
            throws = comp.Throws_input.Value;
            Parallel_Planks = round(comp.Planks.Value);
            limit = 0;

            ylim(comp.UIAxes, [0, width * Parallel_Planks]);
            xlim(comp.UIAxes, [0, width * Parallel_Planks]);

            ticks = 0:width:width * Parallel_Planks;
            xticks(comp.UIAxes, ticks);


            for i = 1:throws

                if limit < 100

                    square_side_x = rand();
                    square_side_y = rand() * width;
                    angle = rand() * (2 * pi);  
            
                    square_x = zeros(1, 4);
                    square_y = zeros(1, 4);
            
                    previous_point_x = square_side_x;
                    previous_point_y = square_side_y;

                    x_scaler = randi([0, (Parallel_Planks-1) * width]);
                    y_scaler = randi([0, (Parallel_Planks-1) * width]);
                    y_scaler = width * floor(y_scaler / width);
            
                    for side = 1:4
                        side_x = previous_point_x + (length * (cos(angle + (side-1) * pi/2 )));
                        side_y = previous_point_y + (length * (sin(angle + (side-1) * pi/2 )));
            
                        previous_point_x = side_x;
                        previous_point_y = side_y;

                        square_x(side) = side_x;
                        square_y(side) = side_y;
                    end

                    square_x = square_x + x_scaler;
                    square_y = square_y + y_scaler;

                    hold(comp.UIAxes, 'on');
                    plot(comp.UIAxes, [square_x, square_x(1)], [square_y, square_y(1)], '-', 'Color', '#376d73');
                    hold(comp.UIAxes, 'off');
                    
                    xlabel(comp.UIAxes, 'X-axis');
                    ylabel(comp.UIAxes, 'Y-axis');
                    title(comp.UIAxes, 'Squares √2 Simulation');
                    
        
                    grid(comp.UIAxes, 'on');
                    set(comp.UIAxes, 'XGrid', 'off');
                    
                    limit = limit + 1;

            
                end
                
            end
        end


        function RunHexagonRootThree(comp)
            width = comp.Width_input.Value;
            length = comp.Length_input.Value;
            throws = comp.Throws_input.Value;
            Parallel_Planks = round(comp.Planks.Value);
            limit = 0;

            ylim(comp.UIAxes, [0, width * Parallel_Planks]);
            xlim(comp.UIAxes, [0, width * Parallel_Planks]);

            ticks = 0:width:width * Parallel_Planks;
            xticks(comp.UIAxes, ticks);

            for i = 1:throws
                if limit < 100

                    hexagon_side_x = rand();
                    hexagon_side_y = rand() * width;
                    angle = rand() * (2 * pi);

                    previous_point_x = hexagon_side_x;
                    previous_point_y = hexagon_side_y;

                    x_scaler = randi([0, (Parallel_Planks-1) * width]);
                    y_scaler = randi([0, (Parallel_Planks-1) * width]);
                    y_scaler = width * floor(y_scaler / width);
            
                    for side = 1:6
                  
                        side_x = previous_point_x + (length * (cos(angle + (side-1) * pi/3)));
                        side_y = previous_point_y + (length * (sin(angle + (side-1) * pi/3)));
                        
                        previous_point_x = side_x;
                        previous_point_y = side_y;
            
                        hexagon_x(side) = side_x;
                        hexagon_y(side) = side_y;
                    
                    end

                    hexagon_x = hexagon_x + x_scaler;
                    hexagon_y = hexagon_y + y_scaler;

                    hold(comp.UIAxes, 'on');
                    plot(comp.UIAxes, [hexagon_x, hexagon_x(1)], [hexagon_y, hexagon_y(1)], '-', 'Color', '#376d73');
                    hold(comp.UIAxes, 'off');

                    grid(comp.UIAxes, 'on');
                    set(comp.UIAxes, 'XGrid', 'off');

                    xlabel(comp.UIAxes, 'X-axis');
                    ylabel(comp.UIAxes, 'Y-axis');
                    title(comp.UIAxes, 'Hexagon √2 Simulation');

                    limit = limit + 1;

                
                end
               
            end
        end

    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CheckBox
        function CheckBoxValueChanged(comp, event)

            value = comp.CheckBox.Value;
            width = comp.Width_input.Value;
            length = comp.Length_input.Value;
            throws = comp.Throws_input.Value;
            Parallel_Planks = round(comp.Planks.Value);

            if Parallel_Planks== 0 || width== 0 || length== 0 || throws == 0 
                error("Don't forget to select all the fields")
            end
            
            if value == 1


                cla(comp.UIAxes);
                grid(comp.UIAxes, 'on');
                set(comp.UIAxes, 'YGrid', 'on', 'YMinorGrid', 'off', 'XGrid', 'off', 'XMinorGrid', 'off');
                set(comp.UIAxes, 'YTick', linspace(0, width * Parallel_Planks, Parallel_Planks + 1));
            
                selectedRadioButton = comp.pick_simulation.SelectedObject;
    
                if selectedRadioButton == comp.Needles_Button
                    comp.RunNeedlesPi();
                    p = BuffonStandardPi(width, length, throws);
                    comp.pi_estimate.Text = sprintf('Estimation of π: %f', p);
                
                elseif selectedRadioButton == comp.Squares_Button
                    comp.RunSquaresPi();
                    p = BuffonSquaresPi(width, length, throws);
                    comp.pi_estimate.Text = sprintf('Estimation of π: %f', p);
                
                elseif selectedRadioButton == comp.Hexagons_Button
                    comp.RunHexagonRootThree();
                    p = BuffonHexagonRootThree(width, length, throws);
                    comp.pi_estimate.Text = sprintf('Estimation of √3 : %f', p);
                
                elseif selectedRadioButton == comp.Square_Root_two_Button
                    comp.RunSquareRootTwo();
                    p = BuffonSquaresRootTwo(width, length, throws);
                    comp.pi_estimate.Text = sprintf('Estimation of √2: %f', p);
                end
   
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 520 636];
            comp.BackgroundColor = [0.9843 0.8431 0.5529];
            comp.UIAxes = uiaxes(comp);
            title(comp.UIAxes, 'Simulation')
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'Y')
            zlabel(comp.UIAxes, 'Z')
            comp.WidthLabel = uilabel(comp);
            comp.WidthLabel.Text = 'Width';
            comp.Width_input = uieditfield(comp, 'numeric');
            comp.Width_input.Value = 2;
            comp.MonteCarloIrrationalNumbersLabel = uilabel(comp);
            comp.MonteCarloIrrationalNumbersLabel.Text = 'Monte Carlo Irrational Numbers';
            comp.Image = uiimage(comp);
            comp.Image.ImageSource = 'pi-image.jpg';
            comp.black_line_title = uilabel(comp);
            comp.number_order_1 = uilabel(comp);
            comp.number_order_1.Text = '1)';
            comp.side_line_3 = uilabel(comp);
            comp.line_title_2 = uilabel(comp);
            comp.side_line_2 = uilabel(comp);
            comp.side_line_1 = uilabel(comp);
            comp.pick_simulation = uibuttongroup(comp);
            comp.pick_simulation.Title = 'Pick the simulation you want to run :';
            comp.Needles_Button = uiradiobutton(comp.pick_simulation);
            comp.Needles_Button.Text = 'Needles π 🪡 ';
            comp.Needles_Button.Value = true;
            comp.Squares_Button = uiradiobutton(comp.pick_simulation);
            comp.Squares_Button.Text = 'Squares π 🎲 ';
            comp.Hexagons_Button = uiradiobutton(comp.pick_simulation);
            comp.Hexagons_Button.Text = 'Hexagons √3🕸️';
            comp.Square_Root_two_Button = uiradiobutton(comp.pick_simulation);
            comp.Square_Root_two_Button.Text = 'Squares √2 ✌️ ';
            comp.line_title_1 = uilabel(comp);
            comp.number_order_2 = uilabel(comp);
            comp.number_order_2.Text = '2)';
            comp.set_values_label = uilabel(comp);
            comp.set_values_label.Text = 'Select the values for the simulation:';
            comp.LengthEditFieldLabel = uilabel(comp);
            comp.LengthEditFieldLabel.Text = 'Length';
            comp.Length_input = uieditfield(comp, 'numeric');
            comp.Length_input.Value = 1;
            comp.Label = uilabel(comp);
            comp.Label.Text = 'Throws';
            comp.Throws_input = uieditfield(comp, 'numeric');
            comp.Throws_input.Value = 1000000;
            comp.ParallelPlanksSliderLabel = uilabel(comp);
            comp.ParallelPlanksSliderLabel.Text = 'Parallel Planks';
            comp.Planks = uislider(comp);
            comp.Planks.Limits = [0 10];
            comp.Planks.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            comp.pi_estimate = uilabel(comp);
            comp.pi_estimate.Text = '';
            comp.number_order_3 = uilabel(comp);
            comp.number_order_3.Text = 'Result';
            comp.CheckBox = uicheckbox(comp);
            comp.CheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBoxValueChanged, true);
            comp.CheckBox.Text = '';
            comp.RUNLabel = uilabel(comp);
            comp.RUNLabel.Text = 'RUN';
        end
    end
end